classdef AppForPixhawk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        UIAxes             matlab.ui.control.UIAxes
        UIAxes3            matlab.ui.control.UIAxes
        Tab2               matlab.ui.container.Tab
        UIAxes2            matlab.ui.control.UIAxes
        BrowseFilesButton  matlab.ui.control.Button
        ListBox            matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        Property % Description
        
    end
    

    methods (Access = private)

        % Button pushed function: BrowseFilesButton
        function BrowseFilesButtonPushed(app, event)
            file = uigetfile
            load(file);
            alt = CTUN(:,8);
            desired_alt = CTUN(:,7);
            drop_mode_signal = RCIN(:,7); %read channel 5
            drop_signal = RCIN(:,9); %read channel 7
            time = CTUN(:,2); %need to use the time scale associated with each variable, i.e. CTUN, RCIN, etc
            time2 = RCIN(:,2);


            plot(app.UIAxes, time, alt,'b', time, desired_alt,'g');
            ylabel(app.UIAxes, 'Altitude');
            plot(app.UIAxes3,  time2, drop_mode_signal,'--m', time2, drop_signal,'--r');
            %hold off
            %hold on

            xlabel(app.UIAxes, 'Time');
            title(app.UIAxes, strcat(file,' - Drop Data'));
            legend(app.UIAxes, 'Altitude', 'Desired Altitude', 'location', 'northwest');
            legend(app.UIAxes3, 'DropMode Signal', 'Drop Signal', 'location', 'northwest');
            
            %hold off
            
            
            roll = ATT(:,4);
            pitch = ATT(:,6);
            yaw = ATT(:,8);
            time = ATT(:,2);
            
            %hold on
            plot(app.UIAxes2, time, roll, 'b', time, pitch, 'g', time, yaw, 'm');
            %plot(app.UIAxes2, );
            %plot(app.UIAxes2, );
            title(app.UIAxes2, strcat(file, ' - Attitude Data'));
            %hold off
            %plot(app.UIAxes2, CTUN(:,2), alt, '--c');
            legend(app.UIAxes2, 'Roll','Pitch','Yaw','Altitude', 'location', 'northwest');


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Altitude and Desired Altitude vs. Time')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Altitude')
            app.UIAxes3 = uiaxes(app.Tab);
            title(app.UIAxes3, 'Drop Mode and Signal')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Signal')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Orientation')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Position')
            app.BrowseFilesButton = uibutton(app.UIFigure, 'push');
            app.BrowseFilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFilesButtonPushed, true);
            app.BrowseFilesButton.Text = 'Browse Files';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Altitude', 'Desired Altitude', 'Drop Signal', 'Drop Mode', 'Roll', 'Pitch', 'Yaw', 'Altitude (2nd graph)'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Desired Altitude'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppForPixhawk

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end